classdef GUI_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ControlmodeButton       matlab.ui.control.Button
        AngleButton             matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        SearchfordevicesButton  matlab.ui.control.Button
        ConnectButton           matlab.ui.control.Button
        AngleEditFieldLabel     matlab.ui.control.Label
        AngleEditField          matlab.ui.control.NumericEditField
        Image                   matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        MonitorsPanel           matlab.ui.container.Panel
        angle_graph             matlab.ui.control.UIAxes
        velocity_graph          matlab.ui.control.UIAxes
        control_pulses          matlab.ui.control.UIAxes
        tuningButton            matlab.ui.control.Button
        KpEditFieldLabel        matlab.ui.control.Label
        KpEditField             matlab.ui.control.NumericEditField
        KiEditFieldLabel        matlab.ui.control.Label
        KiEditField             matlab.ui.control.EditField
        KdEditFieldLabel        matlab.ui.control.Label
        KdEditField             matlab.ui.control.NumericEditField
        VelocityButton          matlab.ui.control.Button
        VelocityEditFieldLabel  matlab.ui.control.Label
        VelocityEditField       matlab.ui.control.NumericEditField
        simulation              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        selectedBT = []
        bluetoothObj = []
        t = 0 ;
        i = 1 ;
        i_old = 1;
        n = 0;
        omega = 0;
        angle = 0;
        pwm = 0;
        inn = 0;
        desired = 0;
        Angle_dg = 0;
        kp = 0;
        ki = 0;
        kd = 0;
        des_omega = 0;
        flag = 0;
        control_flag = 0;
    end
    
    methods (Access = public)
        
        function results = graph(app)
            while 1
                write(app.bluetoothObj,1,'int8')
                pause(0.001) ;
                    readData1 = read(app.bluetoothObj,5,'single') ;
                    readData2 = read(app.bluetoothObj,1,'int16') ;
               
                app.t(app.i) = readData1(1) ;
                app.omega(app.i) = readData1(2) ;
                app.angle(app.i) = readData1(5) ;
                app.pwm(app.i) = readData2(1) ;
                app.inn(app.i) = readData1(3);
                app.desired(app.i)= readData1(4);
                 if app.i == 1
                    l = plot(app.angle_graph, app.angle(app.i), app.t(app.i)) ;
                    m = plot(app.velocity_graph, app.omega(app.i) , app.t(app.i));
                    h = plot(app.control_pulses, app.pwm(app.i), app.t(app.i));
                    l.XDataSource = 'app.t' ;
                    l.YDataSource = 'app.angle' ;
                    m.XDataSource = 'app.t';
                    m.YDataSource = 'app.omega';
                    h.XDataSource = 'app.t';
                    h.YDataSource = 'app.pwm';
                end
                if app.i - app.i_old>=5
                    %         fprintf('time = %.5f, angle = %.5f, omega = %.5f , PWM = %d , motorDirection = %d \n',app.t(app.i),app.angle(app.i),app.omega(app.i),app.pwm(app.i),motorDirection(app.i)) ;
                    app.i_old = app.i ;
                    refreshdata(l,'caller') ;
                    refreshdata(m,'caller') ; 
                    refreshdata(h,'caller') ; drawnow;
                end
                app.i = app.i + 1 ;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.angle_graph.XColor = 1 - app.MonitorsPanel.BackgroundColor;
            app.angle_graph.YColor = 1 - app.MonitorsPanel.BackgroundColor;
            app.velocity_graph.XColor = 1 - app.MonitorsPanel.BackgroundColor;
            app.velocity_graph.YColor = 1 - app.MonitorsPanel.BackgroundColor;
            app.simulation.XColor = 1 - app.MonitorsPanel.BackgroundColor;
            app.simulation.YColor = 1 - app.MonitorsPanel.BackgroundColor;
            app.control_pulses.XColor = 1 - app.MonitorsPanel.BackgroundColor;
            app.control_pulses.YColor = 1 - app.MonitorsPanel.BackgroundColor;
            
            
            app.angle_graph.Color = app.MonitorsPanel.BackgroundColor;
            app.velocity_graph.Color = app.MonitorsPanel.BackgroundColor;
            app.simulation.Color = 1-app.MonitorsPanel.BackgroundColor;
            app.control_pulses.Color = app.MonitorsPanel.BackgroundColor;
           app.UIFigure.Position(2) = 3 ; 


            app.angle_graph.Title.Color = 1 - app.MonitorsPanel.BackgroundColor;
            app.velocity_graph.Title.Color = 1 - app.MonitorsPanel.BackgroundColor;
            app.simulation.Title.Color = 1 - app.MonitorsPanel.BackgroundColor;
            app.control_pulses.Title.Color = 1 - app.MonitorsPanel.BackgroundColor;
            viewer = HelperOrientationViewer();
            x = 0 ;
            pp = poseplot("MeshFileName", "last.STL");
            a = gca
            a.YAxisLocation = 'right' ;
            pp.Parent = app.simulation ;
            app.simulation.View = [-64.4288   21.3730];
            grid(app.simulation)
            box(app.simulation)
            q = eul2quat([0 pi 0]) ;

            set(pp, "Orientation", quaternion(q(1),q(2),q(3),q(4)));drawnow
        end

        % Button pushed function: ControlmodeButton
        function ControlmodeButtonPushed(app, event)
            app.MonitorsPanel.Visible = 1;
            app.UITable.Visible = 0;
            app.AngleButton.Visible = 'on';
            app.angle_graph.Visible = 'on';
            app.velocity_graph.Visible = 'on';
            app.simulation.Visible = 'off';
            app.angle(app.angle_graph.Visible);
            app.VelocityButton.Visible = 1;
            app.tuningButton.Visible = 1;
            graph(app)
        end

        % Button pushed function: AngleButton
        function AngleButtonPushed(app, event)
            app.AngleEditField.Visible = 'on';
            app.KpEditField.Visible = 0;
            app.KdEditField.Visible = 0;
            app.KiEditField.Visible = 0;
            app.VelocityEditField.Visible = 0;
        end

        % Callback function
        function VelocityButtonPushed(app, event)
            
        end

        % Value changed function: AngleEditField
        function AngleEditFieldValueChanged(app, event)
            app.Angle_dg = app.AngleEditField.Value;
            while 1
       write(aerospaceBluetooth,[app.flag,app.control_flag],'int8')
    write(aerospaceBluetooth,[app.des_omega,app.Angle_dg,app.kp,app.ki,app.kd],'single')
            end
        end

        % Callback function
        function VelocityEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function ReadmodeButtonPushed(app, event)

            
        end

        % Callback function
        function AnglegraphButtonPushed(app, event)
            
        end

        % Callback function
        function VelocitygraphButtonPushed(app, event)
            
        end

        % Callback function
        function SimulationButtonPushed(app, event)
            
        end

        % Callback function
        function GraphsButtonPushed(app, event)
            app.angle_graph.Visible = 'on';
            app.velocity_graph.Visible = 'on';
            app.simulation.Visible = 'off';
            graph(app)
        end

        % Button down function: angle_graph
        function angle_graphButtonDown(app, event)
            
        end

        % Button pushed function: SearchfordevicesButton
        function SearchfordevicesButtonPushed(app, event)
            app.KpEditField.Visible = 0;
            app.KdEditField.Visible = 0;
            app.KiEditField.Visible = 0;
            app.AngleEditField.Visible = 0;
            app.VelocityEditField.Visible = 0;
            app.UITable.Visible = 1;
            app.AngleButton.Visible = 0;
            app.VelocityButton.Visible = 0;
            app.tuningButton.Visible = 0;
            app.angle(app.angle_graph.Visible);
            app.angle_graph.Visible = 0;
            app.velocity_graph.Visible = 0;
            app.simulation.Visible = 0;
            app.control_pulses.Visible = 0;
            app.MonitorsPanel.Visible = 0;
            plot(app.angle_graph, 0, 0)
            plot(app.velocity_graph, 0, 0)
            plot(app.control_pulses, 0, 0)
            plot(app.simulation, 0, 0)
            app.UITable.Data = bluetoothlist ;
            try
                app.ConnectButton.Visible = 1 ;
            catch
                
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            delete(instrfind);
            app.bluetoothObj = bluetooth(app.UITable.Data.Address(app.selectedBT),1);
            app.angle_graph.Visible = 0;
            app.velocity_graph.Visible = 0;
            app.simulation.Visible = 0;
            app.control_pulses.Visible = 0;
            graph(app)
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.selectedBT = indices(1) ;
        end

        % Button pushed function: tuningButton
        function tuningButtonPushed(app, event)
            app.KpEditField.Visible = 1;
            app.KdEditField.Visible = 1;
            app.KiEditField.Visible = 1;
            app.AngleEditField.Visible = 0;
            app.VelocityEditField.Visible = 0;
        end

        % Button pushed function: VelocityButton
        function VelocityButtonPushed2(app, event)
            app.KpEditField.Visible = 0;
            app.KdEditField.Visible = 0;
            app.KiEditField.Visible = 0;
            app.AngleEditField.Visible = 0;
            app.VelocityEditField.Visible = 1;
        end

        % Value changed function: VelocityEditField
        function VelocityEditFieldValueChanged2(app, event)
            app.des_omega = app.VelocityEditField.Value;
            while 1
       write(aerospaceBluetooth,[app.flag,app.control_flag],'int8')
    write(aerospaceBluetooth,[app.des_omega,app.Angle_dg,app.kp,app.ki,app.kd],'single')
            end
        end

        % Value changed function: KdEditField
        function KdEditFieldValueChanged(app, event)
            app.kd = app.KdEditField.Value;
             while 1
       write(aerospaceBluetooth,[app.flag,app.control_flag],'int8')
    write(aerospaceBluetooth,[app.des_omega,app.Angle_dg,app.kp,app.ki,app.kd],'single')
            end
        end

        % Value changed function: KiEditField
        function KiEditFieldValueChanged(app, event)
            app.ki = app.KiEditField.Value;
            while 1
       write(aerospaceBluetooth,[app.flag,app.control_flag],'int8')
    write(aerospaceBluetooth,[app.des_omega,app.Angle_dg,app.kp,app.ki,app.kd],'single')
            end
        end

        % Value changed function: KpEditField
        function KpEditFieldValueChanged(app, event)
            app.kp = app.KpEditField.Value;
            while 1
       write(aerospaceBluetooth,[app.flag,app.control_flag],'int8')
    write(aerospaceBluetooth,[app.des_omega,app.Angle_dg,app.kp,app.ki,app.kd],'single')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ControlmodeButton = uibutton(app.UIFigure, 'push');
            app.ControlmodeButton.ButtonPushedFcn = createCallbackFcn(app, @ControlmodeButtonPushed, true);
            app.ControlmodeButton.Text = 'Control mode';
            app.AngleButton = uibutton(app.UIFigure, 'push');
            app.AngleButton.ButtonPushedFcn = createCallbackFcn(app, @AngleButtonPushed, true);
            app.AngleButton.Visible = 'off';
            app.AngleButton.Text = 'Angle';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Visible = 'off';
            app.SearchfordevicesButton = uibutton(app.UIFigure, 'push');
            app.SearchfordevicesButton.ButtonPushedFcn = createCallbackFcn(app, @SearchfordevicesButtonPushed, true);
            app.SearchfordevicesButton.Text = 'Search for devices';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Visible = 'off';
            app.ConnectButton.Text = 'Connect';
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Visible = 'off';
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleEditField.ValueChangedFcn = createCallbackFcn(app, @AngleEditFieldValueChanged, true);
            app.AngleEditField.Visible = 'off';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '1200px-E-JUSTLogo.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'EgsaLogo.png';
            app.MonitorsPanel = uipanel(app.UIFigure);
            app.MonitorsPanel.Title = 'Monitors';
            app.angle_graph = uiaxes(app.MonitorsPanel);
            title(app.angle_graph, 'Angle graph')
            xlabel(app.angle_graph, 'X')
            ylabel(app.angle_graph, 'Y')
            zlabel(app.angle_graph, 'Z')
            app.angle_graph.ButtonDownFcn = createCallbackFcn(app, @angle_graphButtonDown, true);
            app.velocity_graph = uiaxes(app.MonitorsPanel);
            title(app.velocity_graph, {'Velocity graph'; ''})
            xlabel(app.velocity_graph, 'X')
            ylabel(app.velocity_graph, 'Y')
            zlabel(app.velocity_graph, 'Z')
            app.control_pulses = uiaxes(app.MonitorsPanel);
            title(app.control_pulses, 'Control pulses')
            xlabel(app.control_pulses, 'X')
            ylabel(app.control_pulses, 'Y')
            zlabel(app.control_pulses, 'Z')
            app.tuningButton = uibutton(app.UIFigure, 'push');
            app.tuningButton.ButtonPushedFcn = createCallbackFcn(app, @tuningButtonPushed, true);
            app.tuningButton.Visible = 'off';
            app.tuningButton.Text = 'tuning';
            app.KpEditFieldLabel = uilabel(app.UIFigure);
            app.KpEditFieldLabel.Visible = 'off';
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.UIFigure, 'numeric');
            app.KpEditField.ValueChangedFcn = createCallbackFcn(app, @KpEditFieldValueChanged, true);
            app.KpEditField.Visible = 'off';
            app.KiEditFieldLabel = uilabel(app.UIFigure);
            app.KiEditFieldLabel.Visible = 'off';
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.UIFigure, 'text');
            app.KiEditField.ValueChangedFcn = createCallbackFcn(app, @KiEditFieldValueChanged, true);
            app.KiEditField.Visible = 'off';
            app.KdEditFieldLabel = uilabel(app.UIFigure);
            app.KdEditFieldLabel.Visible = 'off';
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.UIFigure, 'numeric');
            app.KdEditField.ValueChangedFcn = createCallbackFcn(app, @KdEditFieldValueChanged, true);
            app.KdEditField.Visible = 'off';
            app.VelocityButton = uibutton(app.UIFigure, 'push');
            app.VelocityButton.ButtonPushedFcn = createCallbackFcn(app, @VelocityButtonPushed2, true);
            app.VelocityButton.Visible = 'off';
            app.VelocityButton.Text = 'Velocity';
            app.VelocityEditFieldLabel = uilabel(app.UIFigure);
            app.VelocityEditFieldLabel.Visible = 'off';
            app.VelocityEditFieldLabel.Text = 'Velocity';
            app.VelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocityEditField.ValueChangedFcn = createCallbackFcn(app, @VelocityEditFieldValueChanged2, true);
            app.VelocityEditField.Visible = 'off';
            app.simulation = uiaxes(app.UIFigure);
            title(app.simulation, 'Simulation')
            xlabel(app.simulation, 'X')
            ylabel(app.simulation, 'Y')
            zlabel(app.simulation, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end